classdef project_ppt < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        NghiemTab                    matlab.ui.container.Tab
        EndEditField                 matlab.ui.control.NumericEditField
        EndEditFieldLabel            matlab.ui.control.Label
        StartEditField               matlab.ui.control.NumericEditField
        StartEditFieldLabel          matlab.ui.control.Label
        nEditField                   matlab.ui.control.NumericEditField
        SlnlpEditFieldLabel          matlab.ui.control.Label
        resultEditField              matlab.ui.control.NumericEditField
        KtquEditFieldLabel           matlab.ui.control.Label
        ppListBox                    matlab.ui.control.ListBox
        PhngphpListBoxLabel          matlab.ui.control.Label
        errorEditField               matlab.ui.control.NumericEditField
        NhpsaischophpEditFieldLabel  matlab.ui.control.Label
        NhpkhongphnlynghimABLabel    matlab.ui.control.Label
        BEditField                   matlab.ui.control.NumericEditField
        BEditFieldLabel              matlab.ui.control.Label
        AEditField                   matlab.ui.control.NumericEditField
        AEditFieldLabel              matlab.ui.control.Label
        inputEditField               matlab.ui.control.EditField
        NhpphngtrnhEditFieldLabel    matlab.ui.control.Label
        RUNButton                    matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
        Tab                          matlab.ui.container.Tab
        Tab2                         matlab.ui.container.Tab
        Tab3_2                       matlab.ui.container.Tab
        KetquaphuongtrinhEditField   matlab.ui.control.EditField
        KetquaphuongtrinhEditField_2Label  matlab.ui.control.Label
        PhuongPhapListBox            matlab.ui.control.ListBox
        PhuongPhapListBox_2Label     matlab.ui.control.Label
        yEditField                   matlab.ui.control.EditField
        yEditField_2Label            matlab.ui.control.Label
        xEditField                   matlab.ui.control.EditField
        xEditField_2Label            matlab.ui.control.Label
        KetquadudoanEditField        matlab.ui.control.NumericEditField
        NhapgiatridudoanEditField    matlab.ui.control.NumericEditField
        RunButton                    matlab.ui.control.Button
        UIAxes_4                     matlab.ui.control.UIAxes
        UIAxes_3                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        flag = 0; % Description
        c = -2;
    end
    
    methods (Access = private)
        
        
        function [x,n] = chiadoi(app, fx, a, b, error)
            % Kiểm tra hàm số liên tục trong khoảng phân ly không
            if fx(a)*fx(b) >= 0
                uialert(app.UIFigure,['Hàm số không liên tục trong khoảng phân ly [a, b]. ' ...
                    'Vui lòng nhập lại [a, b] hoặc hàm số khác!'], 'lỗi');
                x = NaN; n = 0; return;
            end

            n = 1; % Số lần lặp
            max_in = 1000;
            while n < max_in
                x = (a + b)/2;

                if fx(x)*fx(b) > 0
                    b = x;
                else 
                    a = x;
                end

                if abs(b - a) < error
                return;
                end
            n = n + 1;
            end
            uialert(app.UIFigure, 'Đã hết số lần lặp tối đa mà chưa đạt sai số mong muốn.', 'Cảnh báo');
            end
        
        function [x, n] = lap(app, g, a, b, error)
            % Kiểm tra đạo hàm của g có q < 1 trong đoạn [a, b]
            syms x;
            g_sym = str2sym(g);
            dg_sym = diff(g_sym,x);
            dG = matlabFunction(dg_sym);disp(dG)
            if double(dG(a)) > 1 || double(dG(b)) > 1
                uialert(app.UIFigure, ['Đạo hàm g(x) = q > 1, không hội tụ! Vui lòng nhập lại hàm g(x) ' ...
                    'hoặc khoảng phân ly [a, b] khác'], 'Lỗi');
                x = NaN; n = 0; return;
            end
            
            g = str2func(['@(x)', g]);
            n = 1;
            %x0 = (a+b)/2;
            x0 = a;
            max_in = 1000;
            while n < max_in
                x = g(x0);

                % Kiểm tra sai số
                if abs(x - x0) < error
                    return;
                end
                
            n = n + 1;
            x0 = x;
            end
            x = x0; % Nếu vượt quá x bằng giá trị của số lần lặp tối đa
            uialert(app.UIFigure, 'Đã hết số lần lặp tối đa mà chưa đạt sai số mong muốn.', 'Cảnh báo');
        end
    
        
        function [x,n] = newton_raphson(app, fx, a, b, error)
            % Tính đạo hàm
            try
            syms x;
            f_sym = str2sym(fx);

            df_sym = diff(f_sym, x);
            ddf_sym = diff(df_sym, x);

            % Chuyển về hàm số
            F = matlabFunction(f_sym);%disp(F);
            dF = matlabFunction(df_sym);%disp(dF);
            ddF = matlabFunction(ddf_sym);%disp(ddF);
            catch
                uialert(app.UIFigure, 'Lỗi cú pháp phương trình!', 'Lỗi');
                x = NaN; n = 0; return;
            end
            
            % Xét điều kiện hội tụ
            if double(dF(a))*double(dF(b)) < 0
                uialert(app.UIFigure, 'Đạo hàm cấp 1 của hàm số đổi dấu trên khoảng [a, b]. Không hội tụ.', 'Không thỏa điều kiện');
                x = NaN; n = 0; return;
            elseif double(ddF(a))*double(ddF(b)) < 0
                uialert(app.UIFigure, 'Đạo hàm cấp 2 của hàm số đổi dấu trên khoảng [a, b]. Không hội tụ.', 'Không thỏa điều kiện');
                x = NaN; n = 0; return;
            end
            
            if double(F(a))*double(ddF(a)) > 0
                x0 = a;
            else 
                x0 = b;
            end

            %x0 = (a + b)/2;
            n = 0;
            max_in = 1000;
            while n < max_in
                n = n + 1;
                x1 = x0 - double(F(x0))/double(dF(x0));
                if abs(x1 - x0) < error
                    x = x1;
                return;
                end
                x0 = x1;
            end
        x = x1;  % Nếu vượt quá số lần lặp tối đa
        uialert(app.UIFigure, 'Đã hết số lần lặp tối đa mà chưa đạt sai số mong muốn.', 'Cảnh báo');
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Các thông số khởi tạo ban đầu
            app.AEditField.Value = 0;
            app.BEditField.Value = 0.5;
            app.EndEditField.Value = 0.5;
            app.StartEditField.Value = -0.2;
            app.errorEditField.Value = 0.01;

            % Mặc định cho listbox
            app.ppListBox.Value = 'Chia đôi';
            app.inputEditField.Value = '(x-1)^2-1/2*exp(x)';% f(x)

            % QUAN TRỌNG: Kích hoạt nút RUN tự động
            % Gọi hàm callback của nút RUN như một hàm bình thường
            % Tham số thứ 2 là event, truyền [] (rỗng) vì không click chuột thật
            RUNButtonPushed(app, []);

            app.xEditField.Value = '1:9';
            app.yEditField.Value = '1, 1.5, 2, 3, 4, 5, 8, 10, 13';
        
            % Phương pháp mặc định
            app.PhuongPhapListBox.Value = 'Tuyen tinh';
        
            % Giá trị dự đoán mặc định
            app.NhapgiatridudoanEditField.Value = 6;
        
            % Xóa kết quả cũ
            app.KetquaphuongtrinhEditField.Value = '';
            app.KetquadudoanEditField.Value = 0;
        
            % Chuẩn bị trục
            cla(app.UIAxes)
            grid(app.UIAxes,'on')
            title(app.UIAxes,'Do thi hoi quy')
            xlabel(app.UIAxes,'X')
            ylabel(app.UIAxes,'Y')
        end

        % Callback function: EndEditField, RUNButton, StartEditField, 
        % ...and 1 other component
        function RUNButtonPushed(app, event)
            disp("--------- DEBUG: Nhấn nút được! ---------");
            % ----- Dữ liệu đầu vào -----
            endp = app.EndEditField.Value;
            startp = app.StartEditField.Value;
            func_str = app.inputEditField.Value;     

            % VD: x^2 -> x.^2
            draw_str = strrep(func_str, '^', '.^');
            draw_str = strrep(draw_str, '*', '.*');
            draw_str = strrep(draw_str, '/', './');
            func = str2func(['@(x)', draw_str]);
            disp(func);
            % ----- Thông số - tính toán vẽ đồ thị ------
            fs = 10000;
            t = linspace(startp, endp, fs);
            y = func(t);

            % ------ Vẽ đồ thị hàm số ------
            plot(app.UIAxes, real(t), real(y), 'b','LineWidth', 1.6);
            grid(app.UIAxes, 'on');
            hold(app.UIAxes, 'on');

            % ------ Tìm nghiệm xấp xỉ ------
            a = app.AEditField.Value;
            b = app.BEditField.Value;
            error = app.errorEditField.Value;
            
            func_calc = str2func(['@(x)', func_str]);
            selectedOption = app.ppListBox.Value;
            try
                switch selectedOption
                    case 'Chia đôi'
                        [x, n] = app.chiadoi(func_calc, a, b, error);
                        yline(app.UIAxes, 0, 'Color', 'k', 'LineWidth', 1.5);
                        xline(app.UIAxes, 0, 'Color', 'k', 'LineWidth', 1.5);
                        % Vẽ điểm nghiệm
                        plot(app.UIAxes, x, func_calc(x), 'ro', 'MarkerFaceColor', 'r');
                        hold(app.UIAxes, 'off');
                    case 'Lặp'
                        [x, n] = app.lap(func_str, a, b, error);
                        yLine = @(k)(k);
                        plot(app.UIAxes, t, yLine(t), 'g-', 'LineWidth', 1.0);
                        xline(app.UIAxes, x, 'k--', 'LineWidth', 1.0);
                        % Vẽ điểm nghiệm
                        plot(app.UIAxes, real(x), 0, 'ro', 'MarkerFaceColor', 'r');
                        yline(app.UIAxes, 0, 'Color', 'k', 'LineWidth', 1.5);
                        xline(app.UIAxes, 0, 'Color', 'k', 'LineWidth', 1.5);
                        hold(app.UIAxes, 'off');
                    case 'Newton (tiếp tuyến)'
                        [x, n] = app.newton_raphson(func_str, a, b, error);
                        yline(app.UIAxes, 0, 'Color', 'k', 'LineWidth', 1.5);
                        xline(app.UIAxes, 0, 'Color', 'k', 'LineWidth', 1.5);
                        % Vẽ điểm nghiệm
                        plot(app.UIAxes, x, func_calc(x), 'ro', 'MarkerFaceColor', 'r');
                        hold(app.UIAxes, 'off');
                end
                
                % Hiển thị kết quả
                app.nEditField.Value = n;
                app.resultEditField.Value = double(x);
               
            catch ME
                %uialert(app.UIFigure, ME.message, 'Lỗi');%Debug
            end
            
        end

        % Value changed function: inputEditField
        function inputEditFieldValueChanged(app, event)
            currentStr = app.inputEditField.Value;
            if isempty(currentStr)
                app.flag = 0;

                currentMethod = app.ppListBox.Value;
                switch currentMethod
                    case 'Chia đôi'        
                        app.inputEditField.Value = '(x-1)^2-1/2*exp(x)';% f(x)
                    case 'Lặp'
                        app.inputEditField.Value = '1 - sqrt(2)/2*exp(1/2*x)'; % g(x)
                    case 'Newton (tiếp tuyến)'
                        app.inputEditField.Value = '(x-1)^2-1/2*exp(x)';% f(x)
                end
            else
                app.flag = 1;
            end
        end

        % Callback function
        function StartEditFieldValueChanged(app, event)
            
            
        end

        % Callback function
        function EndEditFieldValueChanged(app, event)
            
            
        end

        % Value changed function: ppListBox
        function ppListBoxValueChanged(app, event)
            selectedOption = app.ppListBox.Value;

            if app.flag == 0
                switch selectedOption
                    case 'Chia đôi'        
                        app.inputEditField.Value = '(x-1)^2-1/2*exp(x)';% f(x)
                    case 'Lặp'
                        app.inputEditField.Value = '1 - sqrt(2)/2*exp(1/2*x)'; % g(x)
                    case 'Newton (tiếp tuyến)'
                        app.inputEditField.Value = '(x-1)^2-1/2*exp(x)';% f(x)
                end
            else 
                return;
            end
        end

        % Value changed function: AEditField
        function AEditFieldValueChanged(app, event)
            app.c = -2;
            app.StartEditField.Value = app.AEditField.Value + app.c;
        end

        % Value changed function: BEditField
        function BEditFieldValueChanged(app, event)
            if app.BEditField.Value > 0
                app.c = 2;
            end
            app.EndEditField.Value = app.BEditField.Value + app.c;
        end

        % Callback function
        function errorEditFieldValueChanged(app, event)
            
            
        end

        % Value changed function: resultEditField
        function resultEditFieldValueChanged(app, event)
            
            
        end

        % Value changed function: nEditField
        function nEditFieldValueChanged(app, event)
            
            
        end

        % Callback function
        function RUNButtonPushed2(app, event)
            
        end

        % Callback function: not associated with a component
        function RunButtonPushed(app, event)

        end

        % Button pushed function: RunButton
        function RunButtonPushed2(app, event)
                         x = str2num(app.xEditField.Value); %#ok<ST2NM>
    y = str2num(app.yEditField.Value); %#ok<ST2NM>

    if isempty(x) || isempty(y)
        uialert(app.UIFigure,'Chưa nhập x hoặc y','Lỗi');
        return
    end

    if length(x) ~= length(y)
        uialert(app.UIFigure,'x và y phải cùng số phần tử','Lỗi');
        return
    end

    %% ===== 2. CHỌN PHƯƠNG PHÁP & GỌI HÀM =====
    method = app.PhuongPhapListBox.Value;

    switch method
        case 'Tuyen tinh'
            [a1, a0, y_fit, eqn] = regressionLinear(x, y);

        case 'Ham mu'
            try
                [a, b, y_fit, eqn] = regressionPower(x, y);
            catch ME
                uialert(app.UIFigure, ME.message, 'Lỗi');
                return
            end

        case 'Ham logarit'
            try
                [a, b, y_fit, eqn] = regressionLog(x, y);
            catch ME
                uialert(app.UIFigure, ME.message, 'Lỗi');
                return
            end
    end

    %% ===== 3. HIỂN THỊ PHƯƠNG TRÌNH =====
    app.KetquaphuongtrinhEditField.Value = eqn;

    %% ===== 4. VẼ ĐỒ THỊ =====
    cla(app.UIAxes)
    hold(app.UIAxes,'on')

    plot(app.UIAxes, x, y, 'bo', 'MarkerFaceColor','b')

    xx = linspace(min(x), max(x), 200);

    switch method
        case 'Tuyen tinh'
            yy = a1*xx + a0;
        case 'Ham mu'
            yy = a*xx.^b;
        case 'Ham logarit'
            yy = a*log(xx) + b;
    end

    plot(app.UIAxes, xx, yy, 'r', 'LineWidth', 2)

    grid(app.UIAxes,'on')
    legend(app.UIAxes, {'Dữ liệu','Hồi quy'}, 'Location','best')
    hold(app.UIAxes,'off')

    %% ===== 5. DỰ ĐOÁN =====
    x0 = app.NhapgiatridudoanEditField.Value;

    switch method
        case 'Tuyen tinh'
            y0 = a1*x0 + a0;

        case 'Ham mu'
            if x0 <= 0
                uialert(app.UIFigure,'x0 phải > 0','Lỗi');
                return
            end
            y0 = a*x0^b;

        case 'Ham logarit'
            if x0 <= 0
                uialert(app.UIFigure,'x0 phải > 0','Lỗi');
                return
            end
            y0 = a*log(x0) + b;
    end

    app.KetquadudoanEditField.Value = y0;

    %% ===== 6. VẼ ĐIỂM DỰ ĐOÁN =====
    hold(app.UIAxes,'on')
    plot(app.UIAxes, x0, y0, 'ro', 'MarkerSize', 8, 'LineWidth', 2)
    hold(app.UIAxes,'off')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Do thi hoi quy')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.TabGroup = uitabgroup(app.UIFigure);
            app.NghiemTab = uitab(app.TabGroup);
            app.NghiemTab.Title = 'Nghiệm';
            app.UIAxes = uiaxes(app.NghiemTab);
            title(app.UIAxes, 'Đồ thị hàm số')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'f(x)')
            zlabel(app.UIAxes, 'Z')
            app.RUNButton = uibutton(app.NghiemTab, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.NhpphngtrnhEditFieldLabel = uilabel(app.NghiemTab);
            app.NhpphngtrnhEditFieldLabel.Text = 'Nhập phương trình';
            app.inputEditField = uieditfield(app.NghiemTab, 'text');
            app.inputEditField.ValueChangedFcn = createCallbackFcn(app, @inputEditFieldValueChanged, true);
            app.AEditFieldLabel = uilabel(app.NghiemTab);
            app.AEditFieldLabel.Text = 'A';
            app.AEditField = uieditfield(app.NghiemTab, 'numeric');
            app.AEditField.ValueChangedFcn = createCallbackFcn(app, @AEditFieldValueChanged, true);
            app.BEditFieldLabel = uilabel(app.NghiemTab);
            app.BEditFieldLabel.Text = 'B';
            app.BEditField = uieditfield(app.NghiemTab, 'numeric');
            app.BEditField.ValueChangedFcn = createCallbackFcn(app, @BEditFieldValueChanged, true);
            app.NhpkhongphnlynghimABLabel = uilabel(app.NghiemTab);
            app.NhpkhongphnlynghimABLabel.Text = 'Nhập khoảng phân ly nghiệm [A, B]';
            app.NhpsaischophpEditFieldLabel = uilabel(app.NghiemTab);
            app.NhpsaischophpEditFieldLabel.Text = 'Nhập sai số cho phép';
            app.errorEditField = uieditfield(app.NghiemTab, 'numeric');
            app.errorEditField.ValueChangedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.PhngphpListBoxLabel = uilabel(app.NghiemTab);
            app.PhngphpListBoxLabel.Text = 'Phương pháp';
            app.ppListBox = uilistbox(app.NghiemTab);
            app.ppListBox.Items = {'Chia đôi', 'Lặp', 'Newton (tiếp tuyến)'};
            app.ppListBox.ValueChangedFcn = createCallbackFcn(app, @ppListBoxValueChanged, true);
            app.ppListBox.Value = 'Chia đôi';
            app.KtquEditFieldLabel = uilabel(app.NghiemTab);
            app.KtquEditFieldLabel.Text = 'Kêt quả';
            app.resultEditField = uieditfield(app.NghiemTab, 'numeric');
            app.resultEditField.ValueChangedFcn = createCallbackFcn(app, @resultEditFieldValueChanged, true);
            app.SlnlpEditFieldLabel = uilabel(app.NghiemTab);
            app.SlnlpEditFieldLabel.Text = 'Số lần lặp';
            app.nEditField = uieditfield(app.NghiemTab, 'numeric');
            app.nEditField.ValueChangedFcn = createCallbackFcn(app, @nEditFieldValueChanged, true);
            app.StartEditFieldLabel = uilabel(app.NghiemTab);
            app.StartEditFieldLabel.Text = 'Start';
            app.StartEditField = uieditfield(app.NghiemTab, 'numeric');
            app.StartEditField.ValueChangedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.EndEditFieldLabel = uilabel(app.NghiemTab);
            app.EndEditFieldLabel.Text = 'End';
            app.EndEditField = uieditfield(app.NghiemTab, 'numeric');
            app.EndEditField.ValueChangedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.Tab3_2 = uitab(app.TabGroup);
            app.Tab3_2.Title = 'Tab3';
            app.UIAxes_4 = uiaxes(app.Tab3_2);
            title(app.UIAxes_4, 'Do thi hoi quy')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            zlabel(app.UIAxes_4, 'Z')
            app.RunButton = uibutton(app.Tab3_2, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed2, true);
            app.RunButton.Text = 'Run';
            app.NhapgiatridudoanEditField = uieditfield(app.Tab3_2, 'numeric');
            app.KetquadudoanEditField = uieditfield(app.Tab3_2, 'numeric');
            app.xEditField_2Label = uilabel(app.Tab3_2);
            app.xEditField_2Label.Text = 'x';
            app.xEditField = uieditfield(app.Tab3_2, 'text');
            app.xEditField.Value = '1';
            app.yEditField_2Label = uilabel(app.Tab3_2);
            app.yEditField_2Label.Text = 'y';
            app.yEditField = uieditfield(app.Tab3_2, 'text');
            app.PhuongPhapListBox_2Label = uilabel(app.Tab3_2);
            app.PhuongPhapListBox_2Label.Text = 'Phuong Phap';
            app.PhuongPhapListBox = uilistbox(app.Tab3_2);
            app.PhuongPhapListBox.Items = {'Tuyen tinh', 'Ham mu', 'Ham logarit'};
            app.PhuongPhapListBox.Value = 'Ham mu';
            app.KetquaphuongtrinhEditField_2Label = uilabel(app.Tab3_2);
            app.KetquaphuongtrinhEditField_2Label.Text = 'Ket qua phuong trinh';
            app.KetquaphuongtrinhEditField = uieditfield(app.Tab3_2, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = project_ppt

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end